classdef control_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        ConnectionVariablesPanel      matlab.ui.container.Panel
        SerialPortDropDownLabel       matlab.ui.control.Label
        SerialPortDropDown            matlab.ui.control.DropDown
        ScanButton                    matlab.ui.control.Button
        BaudRateDropDownLabel         matlab.ui.control.Label
        BaudRateDropDown              matlab.ui.control.DropDown
        StatusLampLabel               matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        ConnectButton                 matlab.ui.control.Button
        SingleStepPumpVariablesPanel  matlab.ui.container.Panel
        DiameterEditFieldLabel        matlab.ui.control.Label
        DiameterEditField             matlab.ui.control.NumericEditField
        VolumeEditFieldLabel          matlab.ui.control.Label
        VolumeEditField               matlab.ui.control.NumericEditField
        mmLabel                       matlab.ui.control.Label
        RateTimeEditFieldLabel        matlab.ui.control.Label
        RateTimeEditField             matlab.ui.control.NumericEditField
        DelayEditFieldLabel           matlab.ui.control.Label
        DelayEditField                matlab.ui.control.NumericEditField
        UnitsDropDownLabel            matlab.ui.control.Label
        UnitsDropDown                 matlab.ui.control.DropDown
        volLabel                      matlab.ui.control.Label
        minLabel                      matlab.ui.control.Label
        rateLabel                     matlab.ui.control.Label
        RunControlsPanel              matlab.ui.container.Panel
        StartButton                   matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        CONNECTION = connection; % Chemyx syringe pump
        connected = false;% track connection status
    end
  
    methods (Access = private)
        function app = sendUnitsFromGUI(app)
            app.CONNECTION.setUnits(app.UnitsDropDown.Value);
        end
        function app = sendDiameterFromGUI(app)
            app.CONNECTION.setDiameter(app.DiameterEditField.Value);
        end
        function app = sendVolumeFromGUI(app)
            app.CONNECTION.setVolume(app.VolumeEditField.Value);
        end
        function app = sendRateFromGUI(app)
            app.CONNECTION.setRate(app.RateTimeEditField.Value);
        end
        function app = sendDelayFromGUI(app)
            app.CONNECTION.setDelay(app.DelayEditField.Value);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanButton
        function ScanPorts(app, event)
            % retreive open ports
            ports=getAvailableComPort();
            % populate dropdown
            app.SerialPortDropDown.Items=ports;
        end

        % Button pushed function: ConnectButton
        function ChangeConnection(app, event)
            if app.connected
                % Disconnect by resetting connection class - not necessary
                % openConnection being called again will overwrite
                % basically just locks some variables 
                app.CONNECTION.closeConnection();
                % change GUI display
                app.StatusLamp.Color=[1.00,0.00,0.00]; % set status to red
                app.ConnectButton.Text='Connect';
                app.connected=false;
                % enable connection fields
                app.SerialPortDropDown.Enable=1;
                app.ScanButton.Enable=1;
                app.BaudRateDropDown.Enable=1;
                % disable run controls
                app.StartButton.Enable=0;
                app.PauseButton.Enable=0;
                app.StopButton.Enable=0;
            else
                % Attempt to connect
                port = app.SerialPortDropDown.Value;
                baudrate=str2num(app.BaudRateDropDown.Value);
                try
                    app.CONNECTION.openConnection(port,baudrate);
                    % if successful, change GUI display
                    app.StatusLamp.Color=[0.00,1.00,0.00]; % set status to green
                    app.ConnectButton.Text='Disconnect';
                    app.connected=true;
                    % disable connection fields
                    app.SerialPortDropDown.Enable=0;
                    app.ScanButton.Enable=0;
                    app.BaudRateDropDown.Enable=0;
                    % enable run controls
                    app.StartButton.Enable=1;
                    app.PauseButton.Enable=1;
                    app.StopButton.Enable=1;
                catch ME
                    warning('Failed to connect to pump.')
                    rethrow(ME)
                end
            end
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            if app.connected
                app.sendUnitsFromGUI();
            end
            units=app.UnitsDropDown.Value;
            app.volLabel.Text=units(1:2);
            app.rateLabel.Text=units(4:end);
        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            if app.connected
                app.sendDiameterFromGUI();
            end
        end

        % Value changed function: VolumeEditField
        function VolumeEditFieldValueChanged(app, event)
            if app.connected
                app.sendVolumeFromGUI();
            end
        end

        % Value changed function: RateTimeEditField
        function RateTimeEditFieldValueChanged(app, event)
            if app.connected
                app.sendRateFromGUI();
            end
        end

        % Value changed function: DelayEditField
        function DelayEditFieldValueChanged(app, event)
            if app.connected
                app.sendDelayFromGUI();
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.connected
                % resent pump variables
                app.sendUnitsFromGUI();
                app.sendDiameterFromGUI();
                app.sendVolumeFromGUI();
                app.sendRateFromGUI();
                app.sendDelayFromGUI();
                % start pump
                app.CONNECTION.startPump();
                % update pause button label
                app.PauseButton.Text='Pause';
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.connected
                if all(length(app.PauseButton.Text)==5)
                    app.CONNECTION.pausePump();
                    % update pause button label
                    app.PauseButton.Text='Unpause';
                else
                    app.CONNECTION.startPump();
                    % update pause button label
                    app.PauseButton.Text='Pause';
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.connected
                app.CONNECTION.stopPump();
                % update pause button label
                app.PauseButton.Text='Pause';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-with-tagline@500px.png';
            app.ConnectionVariablesPanel = uipanel(app.UIFigure);
            app.ConnectionVariablesPanel.Title = 'Connection Variables';
            app.SerialPortDropDownLabel = uilabel(app.ConnectionVariablesPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.ConnectionVariablesPanel);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Tag = 'app_serialport';
            app.SerialPortDropDown.Tooltip = {'Select connected serial port'};
            app.SerialPortDropDown.Value = {};
            app.ScanButton = uibutton(app.ConnectionVariablesPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanPorts, true);
            app.ScanButton.Text = 'Scan';
            app.BaudRateDropDownLabel = uilabel(app.ConnectionVariablesPanel);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.ConnectionVariablesPanel);
            app.BaudRateDropDown.Items = {'9600', '14400', '19200', '38400', '57600', '115200'};
            app.BaudRateDropDown.Tooltip = {'Baud rate must match rate set on pump under "System Settings"'};
            app.BaudRateDropDown.Value = '9600';
            app.StatusLampLabel = uilabel(app.ConnectionVariablesPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ConnectionVariablesPanel);
            app.StatusLamp.Tooltip = {'Connection status'};
            app.ConnectButton = uibutton(app.ConnectionVariablesPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeConnection, true);
            app.ConnectButton.Text = 'Connect';
            app.SingleStepPumpVariablesPanel = uipanel(app.UIFigure);
            app.SingleStepPumpVariablesPanel.Title = 'Single-Step Pump Variables';
            app.DiameterEditFieldLabel = uilabel(app.SingleStepPumpVariablesPanel);
            app.DiameterEditFieldLabel.Tooltip = {'Diameter of syringe (mm)'};
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.SingleStepPumpVariablesPanel, 'numeric');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.VolumeEditFieldLabel = uilabel(app.SingleStepPumpVariablesPanel);
            app.VolumeEditFieldLabel.Tooltip = {'Diameter of syringe (mm)'};
            app.VolumeEditFieldLabel.Text = 'Volume';
            app.VolumeEditField = uieditfield(app.SingleStepPumpVariablesPanel, 'numeric');
            app.VolumeEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeEditFieldValueChanged, true);
            app.mmLabel = uilabel(app.SingleStepPumpVariablesPanel);
            app.mmLabel.Text = 'mm';
            app.RateTimeEditFieldLabel = uilabel(app.SingleStepPumpVariablesPanel);
            app.RateTimeEditFieldLabel.Tooltip = {'Diameter of syringe (mm)'};
            app.RateTimeEditFieldLabel.Text = 'Rate/Time';
            app.RateTimeEditField = uieditfield(app.SingleStepPumpVariablesPanel, 'numeric');
            app.RateTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RateTimeEditFieldValueChanged, true);
            app.DelayEditFieldLabel = uilabel(app.SingleStepPumpVariablesPanel);
            app.DelayEditFieldLabel.Tooltip = {'Diameter of syringe (mm)'};
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.SingleStepPumpVariablesPanel, 'numeric');
            app.DelayEditField.ValueChangedFcn = createCallbackFcn(app, @DelayEditFieldValueChanged, true);
            app.UnitsDropDownLabel = uilabel(app.SingleStepPumpVariablesPanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.SingleStepPumpVariablesPanel);
            app.UnitsDropDown.Items = {'mL/min', 'mL/hr', 'μL/min', 'μL/hr'};
            app.UnitsDropDown.ItemsData = {'mL/min', 'mL/hr', 'μL/min', 'μL/hr', ''};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'mL/min';
            app.volLabel = uilabel(app.SingleStepPumpVariablesPanel);
            app.volLabel.Text = 'vol';
            app.minLabel = uilabel(app.SingleStepPumpVariablesPanel);
            app.minLabel.Text = 'min';
            app.rateLabel = uilabel(app.SingleStepPumpVariablesPanel);
            app.rateLabel.Text = 'rate';
            app.RunControlsPanel = uipanel(app.UIFigure);
            app.RunControlsPanel.Title = 'Run Controls';
            app.StartButton = uibutton(app.RunControlsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.RunControlsPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.RunControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end